classdef AppPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Rede Ne...
        ButtonUploadNet        matlab.ui.control.Button           % Load Ne...
        LabelEditField         matlab.ui.control.Label            % File Nama
        FieldNomeFich          matlab.ui.control.EditField        % Pasta_1
        LabelDropDown          matlab.ui.control.Label            % Hidden ...
        DropDownFuncHidden     matlab.ui.control.DropDown         % Step, L...
        Label                  matlab.ui.control.Label            % Output ...
        DropDownFuncOutput     matlab.ui.control.DropDown         % Step, L...
        ButtonGroupAlgoritmo   matlab.ui.container.ButtonGroup    % Train A...
        RadioButtonPTR         matlab.ui.control.RadioButton      % Percept...
        RadioButtonGD          matlab.ui.control.RadioButton      % Gradien...
        RadioButtonSAGD        matlab.ui.control.RadioButton      % Stochas...
        Panel                  matlab.ui.container.Panel          % Rácio 
        LabelNumericEditField  matlab.ui.control.Label            % Train
        NumericEditFieldTreino matlab.ui.control.NumericEditField % [0 100]
        LabelNumericEditField2 matlab.ui.control.Label            % Validation
        NumericEditFieldValidacao matlab.ui.control.NumericEditField % [0 100]
        Label2                 matlab.ui.control.Label            % Test
        NumericEditFieldTeste  matlab.ui.control.NumericEditField % [0 100]
        LabelSwitch            matlab.ui.control.Label           
        SwitchAtivo            matlab.ui.control.Switch           % Off, On
        ButtonTrainNet         matlab.ui.control.Button           % Train N...
        ButtonSaveNet          matlab.ui.control.Button           % Save Ne...
        ButtonUploadImg        matlab.ui.control.Button           % Upload ...
        Label3                 matlab.ui.control.Label            % Epochs
        NumericEditFieldEpoca  matlab.ui.control.NumericEditField % [10 100...
        LabelEditField2        matlab.ui.control.Label            % Hidden ...
        EditFieldCamadas       matlab.ui.control.EditField        % 10
        ButtonUploadAll        matlab.ui.control.Button           % Upload ...
        Desenha                matlab.ui.control.Button           % Draw Le...
        ButtonClassificaLetra  matlab.ui.control.Button           % Classif...
        ButtonSimula           matlab.ui.control.Button           % Classif...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
        end

        % ButtonUploadImg button pushed function
        function ButtonUploadImgButtonPushed(app)
            nomeFicheiro= app.FieldNomeFich.Value;
            if strcmp(nomeFicheiro, 'Pasta_1')
                uploadImage(nomeFicheiro);
            else 
                
                uploadImageSubPasta(nomeFicheiro);
                
            end 
            
            
        end

        % ButtonTrainNet button pushed function
        function ButtonTrainNetButtonPushed(app)
           if app.RadioButtonPTR.Value == 1
               funcaoTreino = 'trainlm';
           elseif app.RadioButtonGD.Value == 1
               funcaoTreino = 'traingd';
           else
               funcaoTreino = 'trainbfg';
           end
           
           camadas = app.EditFieldCamadas.Value;
           
           epoca = app.NumericEditFieldEpoca.Value;
           
           if strcmp(app.DropDownFuncHidden.Value,'Step')
               funcCam1 = 'hardlim'; %(camada 1, step)
           elseif strcmp(app.DropDownFuncHidden.Value,'Linear')
               funcCam1 = 'purelin'; %(camada 1, linear)
           elseif strcmp(app.DropDownFuncHidden.Value,'Sigmoide')
               funcCam1 = 'logsig'; %(camada 1, sigmoid)
           elseif strcmp(app.DropDownFuncHidden.Value,'tanH')
               funcCam1 = 'tansig'; %(camada 1, tanH)
           elseif strcmp(app.DropDownFuncHidden.Value,'Sinal')
               funcCam1 = 'hardlims'; %(camada 1, sinal)
           end
           
           if strcmp(app.DropDownFuncOutput.Value,'Step')
               funcCam2 = 'hardlim'; %(camada 1, step)
           elseif strcmp(app.DropDownFuncOutput.Value,'Linear')
               funcCam2 = 'purelin'; %(camada 1, linear)
           elseif strcmp(app.DropDownFuncOutput.Value,'Sigmoide')
               funcCam2 = 'logsig'; %(camada 1, sigmoid)
           elseif strcmp(app.DropDownFuncOutput.Value,'tanH')
               funcCam2 = 'tansig'; %(camada 1, tanH)
           elseif strcmp(app.DropDownFuncOutput.Value,'Sinal')
               funcCam2 = 'hardlims'; %(camada 1, sinal)
           end
           
           divideFcn = app.SwitchAtivo.Value;
           trainRatio = app.NumericEditFieldTreino.Value/100;
           valRatio = app.NumericEditFieldValidacao.Value/100;
           testRatio = app.NumericEditFieldTeste.Value/100;
           
           [accuracy, accuracy1]=iris_ex(funcaoTreino, camadas, epoca, funcCam1, funcCam2, divideFcn, trainRatio, valRatio, testRatio);
           
          msg=strcat('Total Accuracy:   ', num2str(accuracy));
          msg=strcat(msg, '%');
          uialert(app.UIFigure,msg ,'Train Result','Icon','success');
           
        end

        % ButtonSaveNet button pushed function
        function ButtonSaveNetButtonPushed(app)
            AppGuardarRede;
        end

        % ButtonUploadNet button pushed function
        function ButtonUploadNetButtonPushed(app)
            loadNetwork;
        end

        % ButtonSimula button pushed function
        function ButtonButtonPushed(app)
 
        end

        % ButtonUploadAll button pushed function
        function ButtonUploadAllButtonPushed(app)
            uploadAll;
            
            msg=('All folders from dataset have been uploaded!');
            uialert(app.UIFigure,msg ,'UploadImagem','Icon','success');
        end

        % ButtonClassificaLetra button pushed function
        function ButtonClassificaLetraButtonPushed(app)
            resp=classificaDesenho;
            msg=strcat('Image classified as letter: ', resp);
            uialert(app.UIFigure,msg ,'Classification Result','Icon','success');
        end

        % Desenha button pushed function
        function DesenhaButtonPushed(app)
            system('mspaint &'); 
        end

        % ButtonSimula button pushed function
        function ButtonSimulaButtonPushed(app)
            simular(1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Rede Neuronal';
            setAutoResize(app, app.UIFigure, false)
            app.ButtonUploadNet = uibutton(app.UIFigure, 'push');
            app.ButtonUploadNet.ButtonPushedFcn = createCallbackFcn(app, @ButtonUploadNetButtonPushed);
            app.ButtonUploadNet.Text = 'Load Network';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'File Nama';
            app.FieldNomeFich = uieditfield(app.UIFigure, 'text');
            app.FieldNomeFich.Value = 'Pasta_1';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Hidden layers Function';
            app.DropDownFuncHidden = uidropdown(app.UIFigure);
            app.DropDownFuncHidden.Items = {'Step', 'Linear', 'Sigmoide', 'tanH', 'Sinal'};
            app.DropDownFuncHidden.Value = 'Step';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Output Function';
            app.DropDownFuncOutput = uidropdown(app.UIFigure);
            app.DropDownFuncOutput.Items = {'Step', 'Linear', 'Sigmoide', 'tanH', 'Sinal'};
            app.DropDownFuncOutput.Value = 'Step';
            app.ButtonGroupAlgoritmo = uibuttongroup(app.UIFigure);
            app.ButtonGroupAlgoritmo.Title = 'Train Algorithm';
            app.ButtonGroupAlgoritmo.Units = 'pixels';
            app.RadioButtonPTR = uiradiobutton(app.ButtonGroupAlgoritmo);
            app.RadioButtonPTR.Text = 'Perceptron Training Rule';
            app.RadioButtonPTR.Value = true;
            app.RadioButtonGD = uiradiobutton(app.ButtonGroupAlgoritmo);
            app.RadioButtonGD.Text = 'Gradient Descent';
            app.RadioButtonSAGD = uiradiobutton(app.ButtonGroupAlgoritmo);
            app.RadioButtonSAGD.Text = 'Stochastic Aproximation to Gradient Descent';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Rácio ';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Train';
            app.NumericEditFieldTreino = uieditfield(app.Panel, 'numeric');
            app.NumericEditFieldTreino.Limits = [0 100];
            app.NumericEditFieldTreino.RoundFractionalValues = 'on';
            app.NumericEditFieldTreino.ValueDisplayFormat = '%11.3g';
            app.NumericEditFieldTreino.Value = 70;
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Validation';
            app.NumericEditFieldValidacao = uieditfield(app.Panel, 'numeric');
            app.NumericEditFieldValidacao.Limits = [0 100];
            app.NumericEditFieldValidacao.RoundFractionalValues = 'on';
            app.NumericEditFieldValidacao.ValueDisplayFormat = '%11.3g';
            app.NumericEditFieldValidacao.Value = 15;
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Test';
            app.NumericEditFieldTeste = uieditfield(app.Panel, 'numeric');
            app.NumericEditFieldTeste.Limits = [0 100];
            app.NumericEditFieldTeste.RoundFractionalValues = 'on';
            app.NumericEditFieldTeste.ValueDisplayFormat = '%11.3g';
            app.NumericEditFieldTeste.Value = 15;
            app.LabelSwitch = uilabel(app.Panel);
            app.LabelSwitch.Text = '';
            app.SwitchAtivo = uiswitch(app.Panel, 'slider');
            app.ButtonTrainNet = uibutton(app.UIFigure, 'push');
            app.ButtonTrainNet.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrainNetButtonPushed);
            app.ButtonTrainNet.Text = 'Train Network';
            app.ButtonSaveNet = uibutton(app.UIFigure, 'push');
            app.ButtonSaveNet.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveNetButtonPushed);
            app.ButtonSaveNet.Text = 'Save Network';
            app.ButtonUploadImg = uibutton(app.UIFigure, 'push');
            app.ButtonUploadImg.ButtonPushedFcn = createCallbackFcn(app, @ButtonUploadImgButtonPushed);
            app.ButtonUploadImg.Text = 'Upload Images';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Epochs';
            app.NumericEditFieldEpoca = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditFieldEpoca.Limits = [10 100000];
            app.NumericEditFieldEpoca.RoundFractionalValues = 'on';
            app.NumericEditFieldEpoca.ValueDisplayFormat = '%11.5g';
            app.NumericEditFieldEpoca.Value = 1000;
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Hidden Layers';
            app.EditFieldCamadas = uieditfield(app.UIFigure, 'text');
            app.EditFieldCamadas.Value = '10';
            app.ButtonUploadAll = uibutton(app.UIFigure, 'push');
            app.ButtonUploadAll.ButtonPushedFcn = createCallbackFcn(app, @ButtonUploadAllButtonPushed);
            app.ButtonUploadAll.Text = 'Upload All dataset Images';
            app.Desenha = uibutton(app.UIFigure, 'push');
            app.Desenha.ButtonPushedFcn = createCallbackFcn(app, @DesenhaButtonPushed);
            app.Desenha.Text = 'Draw Letter';
            app.ButtonClassificaLetra = uibutton(app.UIFigure, 'push');
            app.ButtonClassificaLetra.ButtonPushedFcn = createCallbackFcn(app, @ButtonClassificaLetraButtonPushed);
            app.ButtonClassificaLetra.Text = 'Classify image';
            app.ButtonSimula = uibutton(app.UIFigure, 'push');
            app.ButtonSimula.ButtonPushedFcn = createCallbackFcn(app, @ButtonSimulaButtonPushed);
            app.ButtonSimula.Text = 'Classify Folder Images';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppPrincipal()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end